classdef ocr < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        GridLayout              matlab.ui.container.GridLayout
        InputPanel              matlab.ui.container.Panel
        InputGrid               matlab.ui.container.GridLayout
        InputImage              matlab.ui.control.Image
        OutputImage             matlab.ui.control.Image
        SettingsPanel           matlab.ui.container.Panel
        SetttingsGrid           matlab.ui.container.GridLayout
        LayersPanel             matlab.ui.container.Panel
        LayersSelectGrid        matlab.ui.container.GridLayout
        RCheckBox               matlab.ui.control.CheckBox
        GCheckBox               matlab.ui.control.CheckBox
        BCheckBox               matlab.ui.control.CheckBox
        ThresholdPanel          matlab.ui.container.Panel
        SliderLabel             matlab.ui.control.Label
        ThresholdSlider         matlab.ui.control.Slider
        ButtonsPanel            matlab.ui.container.Panel
        ButtonsGrid             matlab.ui.container.GridLayout
        SelectImageButton       matlab.ui.control.Button
        RunOCRButton            matlab.ui.control.Button
        FontSelector            matlab.ui.control.DropDown
        ModePanel               matlab.ui.container.Panel
        GridLayout2             matlab.ui.container.GridLayout
        modeDropdown            matlab.ui.control.DropDown
        ColorInversionCheckBox  matlab.ui.control.CheckBox
        OutputPanel             matlab.ui.container.Panel
        Label_2                 matlab.ui.control.Label
        OCRResultTextArea       matlab.ui.control.TextArea
    end

    
    properties (Access = private)
        binarizer = Binarizer();
        recognizer = Recognizer();
        segmentizer = Segmentizer();
        
        inputImageSet = false;
        inputImage
        binarizedImage;
        output;
    end
    
    methods (Access = private)
        function showBinarized(app)
            if app.inputImageSet
                app.binarizedImage = app.binarizer.binarize(app.inputImage);
                app.OutputImage.ImageSource = 255 * repmat(uint8(app.binarizedImage), [1, 1, 3]);
            end
        end
        
        function displayInputImage(app)
            app.InputImage.ImageSource = app.inputImage;
        end
        
        function writeOutput(app)
            app.OCRResultTextArea.Value = [app.output];
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.binarizer.threshold = app.ThresholdSlider.Value;
            app.binarizer.activeLayers.R = app.RCheckBox.Value;
            app.binarizer.activeLayers.G = app.GCheckBox.Value;
            app.binarizer.activeLayers.B = app.BCheckBox.Value;
               
            font = app.FontSelector.Value;
            
            switch font
                case "Arial"
                    app.recognizer.dataSet = app.recognizer.arialDataSet;
                case "Helvetica"
                    app.recognizer.dataSet = app.recognizer.helveticaDataSet;
                case "TimesNewRoman"
                    app.recognizer.dataSet = app.recognizer.tnrDataSet;
                case "Purisa"
                    app.recognizer.dataSet = app.recognizer.purisaDataSet;
                otherwise
                    app.recognizer.dataSet = app.recognizer.avgDataSet;
            end
        end

        % Value changed function: RCheckBox
        function RCheckBoxValueChanged(app, event)
            value = app.RCheckBox.Value;
            app.binarizer.activeLayers.R = value;
            showBinarized(app);
        end

        % Value changed function: GCheckBox
        function GCheckBoxValueChanged(app, event)
            value = app.GCheckBox.Value;
            app.binarizer.activeLayers.G = value;
            showBinarized(app);
        end

        % Value changed function: BCheckBox
        function BCheckBoxValueChanged(app, event)
            value = app.BCheckBox.Value;
            app.binarizer.activeLayers.B = value;
            showBinarized(app);
        end

        % Value changed function: ThresholdSlider
        function ThresholdSliderValueChanged(app, event)
            value = app.ThresholdSlider.Value;
            app.binarizer.threshold = value;
            showBinarized(app);
        end

        % Button pushed function: SelectImageButton
        function SelectImageButtonPushed(app, event)
            [file,path] = uigetfile('*.JPG;*.PNG');
            
            if ~isequal(file, 0)
                app.inputImageSet = true;
                app.inputImage = double(imread(fullfile(path, file)))/255;
                displayInputImage(app);
                app.showBinarized();
            end
        end

        % Value changed function: FontSelector
        function changeFontSet(app, event)
            value = app.FontSelector.Value;
            switch value
                case "Arial"
                    app.recognizer.dataSet = app.recognizer.arialDataSet;
                case "Helvetica"
                    app.recognizer.dataSet = app.recognizer.helveticaDataSet;
                case "TimesNewRoman"
                    app.recognizer.dataSet = app.recognizer.tnrDataSet;
                case "Purisa"
                    app.recognizer.dataSet = app.recognizer.purisaDataSet;
                otherwise
                    app.recognizer.dataSet = app.recognizer.avgDataSet;
            end
        end

        % Button pushed function: RunOCRButton
        function RunOCR(app, event)
            if app.InputImage.ImageSource == app.inputImage
                out = "";
                letters = app.segmentizer.segmentize(app.binarizedImage)';
                for i = 1:size(letters)
                    if isa(letters{i}, 'char')
                        out = out + letters(i);
                    else
                        box = letters(i);
                        image = box{1, 1};
                        out = out + app.recognizer.recognizeCharacter(image);
                    end
                    app.output = out;
                    writeOutput(app);
                end
            end
        end

        % Value changed function: modeDropdown
        function modeDropdownValueChanged(app, event)
            value = app.modeDropdown.Value;
            app.binarizer.mode = value;
            app.showBinarized();
        end

        % Value changed function: ColorInversionCheckBox
        function ColorInversionCheckBoxValueChanged(app, event)
            value = app.ColorInversionCheckBox.Value;
            app.binarizer.isColorInversionOn = value;
            app.showBinarized();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.InputPanel = uipanel(app.GridLayout);
            app.InputPanel.Title = 'Input';
            app.InputGrid = uigridlayout(app.InputPanel);
            app.InputImage = uiimage(app.InputGrid);
            app.OutputImage = uiimage(app.InputGrid);
            app.SettingsPanel = uipanel(app.GridLayout);
            app.SettingsPanel.Title = 'Settings';
            app.SetttingsGrid = uigridlayout(app.SettingsPanel);
            app.LayersPanel = uipanel(app.SetttingsGrid);
            app.LayersPanel.Title = 'Layers';
            app.LayersSelectGrid = uigridlayout(app.LayersPanel);
            app.RCheckBox = uicheckbox(app.LayersSelectGrid);
            app.RCheckBox.ValueChangedFcn = createCallbackFcn(app, @RCheckBoxValueChanged, true);
            app.RCheckBox.Text = 'R';
            app.GCheckBox = uicheckbox(app.LayersSelectGrid);
            app.GCheckBox.ValueChangedFcn = createCallbackFcn(app, @GCheckBoxValueChanged, true);
            app.GCheckBox.Text = 'G';
            app.BCheckBox = uicheckbox(app.LayersSelectGrid);
            app.BCheckBox.ValueChangedFcn = createCallbackFcn(app, @BCheckBoxValueChanged, true);
            app.BCheckBox.Text = 'B';
            app.ThresholdPanel = uipanel(app.SetttingsGrid);
            app.ThresholdPanel.Title = 'Threshold';
            app.SliderLabel = uilabel(app.ThresholdPanel);
            app.SliderLabel.Text = '';
            app.ThresholdSlider = uislider(app.ThresholdPanel);
            app.ThresholdSlider.Limits = [0 1];
            app.ThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @ThresholdSliderValueChanged, true);
            app.ButtonsPanel = uipanel(app.SetttingsGrid);
            app.ButtonsGrid = uigridlayout(app.ButtonsPanel);
            app.SelectImageButton = uibutton(app.ButtonsGrid, 'push');
            app.SelectImageButton.ButtonPushedFcn = createCallbackFcn(app, @SelectImageButtonPushed, true);
            app.SelectImageButton.Text = 'Select Image';
            app.RunOCRButton = uibutton(app.ButtonsGrid, 'push');
            app.RunOCRButton.ButtonPushedFcn = createCallbackFcn(app, @RunOCR, true);
            app.RunOCRButton.Text = 'Run OCR';
            app.FontSelector = uidropdown(app.ButtonsGrid);
            app.FontSelector.Items = {'Arial', 'Helvetica', 'TimesNewRoman', 'Purisa', 'Other'};
            app.FontSelector.ValueChangedFcn = createCallbackFcn(app, @changeFontSet, true);
            app.FontSelector.Value = 'Arial';
            app.ModePanel = uipanel(app.SetttingsGrid);
            app.ModePanel.Title = 'Mode';
            app.GridLayout2 = uigridlayout(app.ModePanel);
            app.modeDropdown = uidropdown(app.GridLayout2);
            app.modeDropdown.Items = {'manual', 'auto', 'dynamic'};
            app.modeDropdown.ValueChangedFcn = createCallbackFcn(app, @modeDropdownValueChanged, true);
            app.modeDropdown.Value = 'manual';
            app.ColorInversionCheckBox = uicheckbox(app.GridLayout2);
            app.ColorInversionCheckBox.ValueChangedFcn = createCallbackFcn(app, @ColorInversionCheckBoxValueChanged, true);
            app.ColorInversionCheckBox.Text = 'Color Inversion';
            app.OutputPanel = uipanel(app.GridLayout);
            app.OutputPanel.Title = 'Output';
            app.Label_2 = uilabel(app.OutputPanel);
            app.Label_2.Text = '';
            app.OCRResultTextArea = uitextarea(app.OutputPanel);
            app.OCRResultTextArea.Value = {'OCR Results go here.'};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ocr

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end